classdef Peak_Analyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        PeakAnalyzerLabel       matlab.ui.control.Label
        LeftAxisDropDownLabel   matlab.ui.control.Label
        LeftAxisDropDown        matlab.ui.control.DropDown
        RightAxisDropDownLabel  matlab.ui.control.Label
        RightAxisDropDown       matlab.ui.control.DropDown
        PlotPeakInfoButton      matlab.ui.control.Button
        PlotPeakAtTempButton    matlab.ui.control.Button
        TempEditFieldLabel      matlab.ui.control.Label
        TempEditField           matlab.ui.control.NumericEditField
        KLabel                  matlab.ui.control.Label
        SeperatePlotButton      matlab.ui.control.Button
        ZoomFitRegionForTrackedPeakCheckBox  matlab.ui.control.CheckBox
    end


    properties (Access = private)
        tdms_data % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, tdms_data_all)
            app.tdms_data = tdms_data_all;
        end

        % Button pushed function: PlotPeakInfoButton
        function PlotPeakInfoButtonPushed(app, event)
           
            % Plot: based on dropdown settings
            Analyze_Tracked_Peaks(app.UIAxes, ...
                                 {app.tdms_data.peaks_tracked}, ...
                                 [app.tdms_data.temperature],   ...
                                 app.LeftAxisDropDown.Value,    ...
                                 app.RightAxisDropDown.Value)
                            
        end

        % Button pushed function: SeperatePlotButton
        function SeperatePlotButtonPushed(app, event)
          
            % Plot: based on dropdown settings
            figure()
            Analyze_Tracked_Peaks(gca, ...
                                 {app.tdms_data.peaks_tracked}, ...
                                 [app.tdms_data.temperature],   ...
                                 app.LeftAxisDropDown.Value,    ...
                                 app.RightAxisDropDown.Value)
                            
        end

        % Button pushed function: PlotPeakAtTempButton
        function PlotPeakAtTempButtonPushed(app, event)
            
            % Get: the tdms struct that contains the given
            %      temperature
            given_temp = app.TempEditField.Value;
            tdms_at_temp = 0;
            for i = 1:length(app.tdms_data)
                if app.tdms_data(i).temperature == given_temp
                    tdms_at_temp = app.tdms_data(i); 
                    break
                end
            end

            % Set: info to plot
            plot_info = Get_Plot_Struct();
            plot_info.raw = 1;
            plot_info.peaks_raw_set = [1,1];
            plot_info.preprocess = tdms_at_temp.plot_info.preprocess;
            
            % Plot
            figure();
            ax = gca;
            Plot_File(ax, tdms_at_temp,plot_info);
            
            % Set: Axis Info
            title(ax,['Temperature: ',num2str(given_temp)]);
            ylabel(ax,"Voltage (V)");
            xlabel(ax,"Frequency (khz)");
            
            % PLOT: Peak Being Tracked
           
            % Get: Frequency of peak corresponding to the temperature
            pt = tdms_at_temp.peaks_tracked;
            peak_freq = pt.Frequencies;

            % Plot: dashed line at the peak of interest
            y1=get(gca,'ylim');
            plot(ax,[peak_freq, peak_freq],y1,'r--','DisplayName','Peak Frequency Being Tracked');
            
            % Zoom:
            if app.ZoomFitRegionForTrackedPeakCheckBox.Value == true
                
                % min/max freq
                min_freq = min(pt.fit.frequencies);
                max_freq = max(pt.fit.frequencies);
                
                freq_bool = min_freq <= tdms_at_temp.frequency & ...
                            tdms_at_temp.frequency <= max_freq;
                signal_values = [tdms_at_temp.signal_y(freq_bool), ...
                    tdms_at_temp.signal_x(freq_bool)];
                
                ylim(gca,[min([signal_values,pt.fit.signal_x,pt.fit.signal_y]), ...
                    max([signal_values,pt.fit.signal_x,pt.fit.signal_y])]);
                xlim(gca,[min(pt.fit.frequencies), max(pt.fit.frequencies)])
            end
            legend(ax)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.PeakAnalyzerLabel = uilabel(app.UIFigure);
            app.PeakAnalyzerLabel.Text = 'Peak Analyzer';
            app.LeftAxisDropDownLabel = uilabel(app.UIFigure);
            app.LeftAxisDropDownLabel.Text = 'Left Axis';
            app.LeftAxisDropDown = uidropdown(app.UIFigure);
            app.LeftAxisDropDown.Items = {'gamma', 'f_0', 'theta', 'A', 'frequency', 'V'};
            app.LeftAxisDropDown.Value = 'gamma';
            app.RightAxisDropDownLabel = uilabel(app.UIFigure);
            app.RightAxisDropDownLabel.Text = 'Right Axis';
            app.RightAxisDropDown = uidropdown(app.UIFigure);
            app.RightAxisDropDown.Items = {'None', 'gamma', 'f_0', 'theta', 'A', 'V'};
            app.RightAxisDropDown.Value = 'None';
            app.PlotPeakInfoButton = uibutton(app.UIFigure, 'push');
            app.PlotPeakInfoButton.ButtonPushedFcn = createCallbackFcn(app, @PlotPeakInfoButtonPushed, true);
            app.PlotPeakInfoButton.Text = 'Plot Peak Info';
            app.PlotPeakAtTempButton = uibutton(app.UIFigure, 'push');
            app.PlotPeakAtTempButton.ButtonPushedFcn = createCallbackFcn(app, @PlotPeakAtTempButtonPushed, true);
            app.PlotPeakAtTempButton.Text = 'Plot Peak At Temp';
            app.TempEditFieldLabel = uilabel(app.UIFigure);
            app.TempEditFieldLabel.Text = 'Temp';
            app.TempEditField = uieditfield(app.UIFigure, 'numeric');
            app.TempEditField.Limits = [0 1000];
            app.KLabel = uilabel(app.UIFigure);
            app.KLabel.Text = 'K';
            app.SeperatePlotButton = uibutton(app.UIFigure, 'push');
            app.SeperatePlotButton.ButtonPushedFcn = createCallbackFcn(app, @SeperatePlotButtonPushed, true);
            app.SeperatePlotButton.Text = 'Seperate Plot';
            app.ZoomFitRegionForTrackedPeakCheckBox = uicheckbox(app.UIFigure);
            app.ZoomFitRegionForTrackedPeakCheckBox.Text = 'Zoom Fit Region For Tracked Peak';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Peak_Analyzer(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end